classdef crack_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CrackGUIUIFigure                matlab.ui.Figure
        DetectionDirEditFieldLabel      matlab.ui.control.Label
        DetectionDirEditField           matlab.ui.control.EditField
        BrowseDet                       matlab.ui.control.Button
        BrowseGt                        matlab.ui.control.Button
        GroundTruthDirEditFieldLabel    matlab.ui.control.Label
        GroundTruthDirEditField         matlab.ui.control.EditField
        ThresholdIndependentMetricsPanel  matlab.ui.container.Panel
        Compute_sODS_sOIS               matlab.ui.control.Button
        AIUEditFieldLabel               matlab.ui.control.Label
        AIUEditField                    matlab.ui.control.NumericEditField
        sODSEditFieldLabel              matlab.ui.control.Label
        sODSEditField                   matlab.ui.control.NumericEditField
        sOISEditFieldLabel              matlab.ui.control.Label
        sOISEditField                   matlab.ui.control.NumericEditField
        AvgFEditFieldLabel              matlab.ui.control.Label
        AvgFEditField                   matlab.ui.control.NumericEditField
        pxtoleranceCheckBox             matlab.ui.control.CheckBox
        ThresholdDependentMetricsPanel  matlab.ui.container.Panel
        Compute_IoU                     matlab.ui.control.Button
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        IoUEditFieldLabel               matlab.ui.control.Label
        IoUEditField                    matlab.ui.control.NumericEditField
        DiceEditFieldLabel              matlab.ui.control.Label
        DiceEditField                   matlab.ui.control.NumericEditField
        pxtoleranceCheckBox_2           matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        ThrshField                      matlab.ui.control.NumericEditField
        OwntolerancethresholdindependentPanel  matlab.ui.container.Panel
        ODSEditFieldLabel               matlab.ui.control.Label
        ODSEditField                    matlab.ui.control.NumericEditField
        OISEditFieldLabel               matlab.ui.control.Label
        OISEditField                    matlab.ui.control.NumericEditField
        Compute_ODS_OIS                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        settings_my % Keeps directories names
    end

    methods (Access = private)
    
        function load_settings(app)
%             f_set = './settings.mat';
%             if isfile(f_set)
%                 load(f_set, 'settings_my0');
%             else
%                 settings_my0.det_path = '';
%                 settings_my0.gt_path = '';
%                 save(f_set, 'settings_my0');
%             end
%             app.settings_my = settings_my0;
            app.settings_my.det_path = read_txt(app, 'det_path.txt');
            app.settings_my.gt_path = read_txt(app, 'gt_path.txt');
            fprintf('LOAD: det=%s; gt=%s\n', app.settings_my.det_path,app.settings_my.gt_path);
        end
        
        function save_settings(app)
            %settings_my0 = app.settings_my;
            %save('./settings.mat', 'settings_my0');
            write_txt(app, 'det_path.txt', app.settings_my.det_path);
            write_txt(app, 'gt_path.txt', app.settings_my.gt_path);
            fprintf('SAVE: det=%s; gt=%s\n', app.settings_my.det_path,app.settings_my.gt_path);
            
        end
        
        function results = read_txt(app, fn)
            results = '';
            if isfile(fn)
                 id = fopen(fn, 'r');
                 results = fscanf(id, '%s\n');
                 fclose(id);
             end
        end
        
        function write_txt(app, fn, txt)
            id = fopen(fn, 'w');
            if id < 0, return; end
            fprintf(id, '%s\n', txt);
            fclose(id);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            load_settings(app);
            app.DetectionDirEditField.Value = app.settings_my.det_path;
            normalize_dir(app.settings_my.det_path);
            app.GroundTruthDirEditField.Value = app.settings_my.gt_path;
%             if isempty(gcp('nocreate')) % check if parallel pool already started
%                 fprintf('Parallel pool is not running, please wait...\n');
%                 fig = findall(0, 'HandleVisibility', 'off');
%                 d = uiprogressdlg(fig,'Title','Starting parallel pool, it may take several seconds...',...
%                     'Indeterminate','on');
%                 drawnow
%                 parpool % start if not
%                 close(d);
%             end
            %app.HandleVisibility = 'on';
        end

        % Button pushed function: BrowseDet
        function BrowseDetPushed(app, event)
            tmp_dir = uigetdir(app.DetectionDirEditField.Value, 'Please, select detections dir');
            if ~tmp_dir
                f=0;
            else
                app.DetectionDirEditField.Value = tmp_dir;
            end
            drawnow;
            figure(app.CrackGUIUIFigure);
            %tmp_dir = app.settings_my.det_path;
            if ~tmp_dir, return; end
            app.settings_my.det_path = tmp_dir;
            
            %settings_my = app.settings_my;
            fig =app.CrackGUIUIFigure;% findall(0, 'HandleVisibility', 'off');
            d = uiprogressdlg(fig,'Title','Computing sODS/sOIS',...
                'Indeterminate','on');
            drawnow
            normalize_dir(app.settings_my.det_path);
            close(d);
            %save('settings.mat', 'settings_my');
            save_settings(app);
        end

        % Button pushed function: BrowseGt
        function BrowseGtButtonPushed(app, event)
            %tmp_dir = uigetdir;
            tmp_dir = uigetdir(app.GroundTruthDirEditField.Value, 'Please, select ground truth dir');
            drawnow;
            figure(app.CrackGUIUIFigure);
            if ~tmp_dir, return; end
            app.GroundTruthDirEditField.Value = tmp_dir ;
            app.settings_my.gt_path = tmp_dir ;
            %settings_my = app.settings_my;
            %save('settings.mat', 'settings_my');
            save_settings(app);
        end

        % Callback function
        function Compute_AIUButtonPushed(app, event)
            detPath = app.settings_my.det_path;
            gtPath = app.settings_my.gt_path;
            fig = app.CrackGUIUIFigure;%findall(0, 'HandleVisibility', 'off');
            d = uiprogressdlg(fig,'Title','Computing AIU...',...
                    'Indeterminate','on');
            drawnow
            tol2px=app.pxtoleranceCheckBox.Value;
            aiu = AIU_GUI(detPath, gtPath);
            close(d);
            fprintf('AIU = %g\n', aiu);
            app.AIUEditField.Value = aiu;
            %set(handles.aiu,'string',aiu); 
        end

        % Button pushed function: Compute_sODS_sOIS
        function Compute_sODS_sOISButtonPushed(app, event)
            det_dir = app.settings_my.det_path;
            gt_dir = app.settings_my.gt_path;
            run_stage1 = true;
            
            fig = app.CrackGUIUIFigure;%findall(0, 'HandleVisibility', 'off');
            d = uiprogressdlg(fig,'Title','Computing sODS/sOIS', 'Indeterminate','on');
            %d = uiprogressdlg(app.CrackGUIUIFigure,'Title','Computing sODS/sOIS', 'Indeterminate','on');
            drawnow
            tol2px=app.pxtoleranceCheckBox.Value;
            %[sODS, sOIS, aiu, af] = sODS_sOIS(det_dir, gt_dir, run_stage1, tol2px);
            [sODS, sOIS, aiu, af, Fth, IoUth] = sODS_sOISnew(det_dir, gt_dir, run_stage1, tol2px);
            data2save = [ [0.01:0.01:0.99]'  , Fth,IoUth];
            [~, basename,~]=fileparts(det_dir);
            fsave=[basename, '.tsv'];
            dlmwrite(fsave, data2save,'delimiter', '\t', 'precision', '%10.5f');
            close(d);
            app.sODSEditField.Value = sODS;
            app.sOISEditField.Value = sOIS;
            app.AIUEditField.Value = aiu;
            app.AvgFEditField.Value =af;
            plot(app.UIAxes, 0.01:0.01:0.99, Fth);
            hold(app.UIAxes, 'on' );
            plot(app.UIAxes, 0.01:0.01:0.99, IoUth);
            ylim(app.UIAxes, [0, 1]); 
            grid(app.UIAxes, 'on');
            grid(app.UIAxes, 'minor');
            legend(app.UIAxes, 'F', 'IoU', 'Location', 'SouthEast');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: Compute_ODS_OIS
        function Compute_ODS_OISButtonPushed(app, event)
            det_dir = app.settings_my.det_path;
            gt_dir = app.settings_my.gt_path;
            run_stage1 = true;
            fig =app.CrackGUIUIFigure;% findall(0, 'HandleVisibility', 'off');
            d = uiprogressdlg(fig,'Title','Computing ODS/OIS',...
                'Indeterminate','on');
            drawnow
            if isempty(gcp('nocreate')) % check if parallel pool already started
                fprintf('Parallel pool is not running, please wait...\n');
                parpool % start if not
            end
            [ODS, OIS] = ODS_OIS(det_dir, gt_dir, run_stage1);
            close(d);
            app.ODSEditField.Value = ODS;
            app.OISEditField.Value = OIS;
        end

        % Button pushed function: Compute_IoU
        function Compute_IoUButtonPushed(app, event)
            threshold = app.ThresholdSlider.Value / 100;
            det_dir = app.settings_my.det_path;
            gt_dir = app.settings_my.gt_path;
            fig = app.CrackGUIUIFigure;%findall(0, 'HandleVisibility', 'off');
            d = uiprogressdlg(fig,'Title','Computing IoU/Dice/F',...
                'Indeterminate','on');
            drawnow
            %[iou,dice] = IoU(gt_dir, det_dir, threshold);
            tol2px=app.pxtoleranceCheckBox_2.Value;
            [iou,dice, ~] = IoU_F(gt_dir, det_dir, threshold, tol2px);
            close(d);
            app.IoUEditField.Value = iou;
            app.DiceEditField.Value = dice;
            %app.AvgFEditField.Value = F;
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.ThrshField.Value = value;
        end

        % Close request function: CrackGUIUIFigure
        function CrackGUIUIFigureCloseRequest(app, event)
            % TODO: save all settings
            save_settings(app);
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CrackGUIUIFigure = uifigure;
            app.CrackGUIUIFigure.Name = 'Crack GUI';
            app.CrackGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @CrackGUIUIFigureCloseRequest, true);
            app.DetectionDirEditFieldLabel = uilabel(app.CrackGUIUIFigure);
            app.DetectionDirEditFieldLabel.Text = 'Detection Dir';
            app.DetectionDirEditField = uieditfield(app.CrackGUIUIFigure, 'text');
            app.BrowseDet = uibutton(app.CrackGUIUIFigure, 'push');
            app.BrowseDet.ButtonPushedFcn = createCallbackFcn(app, @BrowseDetPushed, true);
            app.BrowseDet.Text = 'Browse';
            app.BrowseGt = uibutton(app.CrackGUIUIFigure, 'push');
            app.BrowseGt.ButtonPushedFcn = createCallbackFcn(app, @BrowseGtButtonPushed, true);
            app.BrowseGt.Text = 'Browse';
            app.GroundTruthDirEditFieldLabel = uilabel(app.CrackGUIUIFigure);
            app.GroundTruthDirEditFieldLabel.Text = 'Ground Truth Dir';
            app.GroundTruthDirEditField = uieditfield(app.CrackGUIUIFigure, 'text');
            app.ThresholdIndependentMetricsPanel = uipanel(app.CrackGUIUIFigure);
            app.ThresholdIndependentMetricsPanel.Title = 'Threshold Independent Metrics';
            app.Compute_sODS_sOIS = uibutton(app.ThresholdIndependentMetricsPanel, 'push');
            app.Compute_sODS_sOIS.ButtonPushedFcn = createCallbackFcn(app, @Compute_sODS_sOISButtonPushed, true);
            app.Compute_sODS_sOIS.Text = 'Compute';
            app.AIUEditFieldLabel = uilabel(app.ThresholdIndependentMetricsPanel);
            app.AIUEditFieldLabel.Text = 'AIU';
            app.AIUEditField = uieditfield(app.ThresholdIndependentMetricsPanel, 'numeric');
            app.AIUEditField.Limits = [0 1];
            app.AIUEditField.Editable = 'off';
            app.sODSEditFieldLabel = uilabel(app.ThresholdIndependentMetricsPanel);
            app.sODSEditFieldLabel.Text = 'sODS';
            app.sODSEditField = uieditfield(app.ThresholdIndependentMetricsPanel, 'numeric');
            app.sODSEditField.Limits = [0 1];
            app.sODSEditField.Editable = 'off';
            app.sOISEditFieldLabel = uilabel(app.ThresholdIndependentMetricsPanel);
            app.sOISEditFieldLabel.Text = 'sOIS';
            app.sOISEditField = uieditfield(app.ThresholdIndependentMetricsPanel, 'numeric');
            app.sOISEditField.Limits = [0 1];
            app.sOISEditField.Editable = 'off';
            app.AvgFEditFieldLabel = uilabel(app.ThresholdIndependentMetricsPanel);
            app.AvgFEditFieldLabel.Text = 'Avg F';
            app.AvgFEditField = uieditfield(app.ThresholdIndependentMetricsPanel, 'numeric');
            app.AvgFEditField.Limits = [0 1];
            app.AvgFEditField.Editable = 'off';
            app.pxtoleranceCheckBox = uicheckbox(app.ThresholdIndependentMetricsPanel);
            app.pxtoleranceCheckBox.Text = '2 px tolerance';
            app.ThresholdDependentMetricsPanel = uipanel(app.CrackGUIUIFigure);
            app.ThresholdDependentMetricsPanel.Title = 'Threshold Dependent Metrics';
            app.Compute_IoU = uibutton(app.ThresholdDependentMetricsPanel, 'push');
            app.Compute_IoU.ButtonPushedFcn = createCallbackFcn(app, @Compute_IoUButtonPushed, true);
            app.Compute_IoU.Text = 'Compute';
            app.ThresholdSliderLabel = uilabel(app.ThresholdDependentMetricsPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.ThresholdDependentMetricsPanel);
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 50;
            app.IoUEditFieldLabel = uilabel(app.ThresholdDependentMetricsPanel);
            app.IoUEditFieldLabel.Text = 'IoU';
            app.IoUEditField = uieditfield(app.ThresholdDependentMetricsPanel, 'numeric');
            app.IoUEditField.Limits = [0 1];
            app.DiceEditFieldLabel = uilabel(app.ThresholdDependentMetricsPanel);
            app.DiceEditFieldLabel.Text = 'Dice';
            app.DiceEditField = uieditfield(app.ThresholdDependentMetricsPanel, 'numeric');
            app.pxtoleranceCheckBox_2 = uicheckbox(app.ThresholdDependentMetricsPanel);
            app.pxtoleranceCheckBox_2.Text = '2 px tolerance';
            app.Label = uilabel(app.CrackGUIUIFigure);
            app.Label.Text = '';
            app.ThrshField = uieditfield(app.CrackGUIUIFigure, 'numeric');
            app.ThrshField.Limits = [0 100];
            app.ThrshField.RoundFractionalValues = 'on';
            app.ThrshField.Editable = 'off';
            app.ThrshField.Value = 50;
            app.OwntolerancethresholdindependentPanel = uipanel(app.CrackGUIUIFigure);
            app.OwntolerancethresholdindependentPanel.Title = 'Own tolerance, threshold independent';
            app.ODSEditFieldLabel = uilabel(app.OwntolerancethresholdindependentPanel);
            app.ODSEditFieldLabel.Text = 'ODS';
            app.ODSEditField = uieditfield(app.OwntolerancethresholdindependentPanel, 'numeric');
            app.ODSEditField.Limits = [0 1];
            app.ODSEditField.Editable = 'off';
            app.OISEditFieldLabel = uilabel(app.OwntolerancethresholdindependentPanel);
            app.OISEditFieldLabel.Text = 'OIS';
            app.OISEditField = uieditfield(app.OwntolerancethresholdindependentPanel, 'numeric');
            app.OISEditField.Limits = [0 1];
            app.OISEditField.Editable = 'off';
            app.Compute_ODS_OIS = uibutton(app.OwntolerancethresholdindependentPanel, 'push');
            app.Compute_ODS_OIS.ButtonPushedFcn = createCallbackFcn(app, @Compute_ODS_OISButtonPushed, true);
            app.Compute_ODS_OIS.Text = 'Compute';
            app.UIAxes = uiaxes(app.CrackGUIUIFigure);
            title(app.UIAxes, 'F(t) and IoU(t)')
            xlabel(app.UIAxes, 'threshold')
        end
    end

    methods (Access = public)

        % Construct app
        function app = crack_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CrackGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CrackGUIUIFigure)
        end
    end
end